classdef assignment1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        loadButton          matlab.ui.control.Button
        binarizationButton  matlab.ui.control.Button
        flipButton          matlab.ui.control.Button
        UIAxes_3            matlab.ui.control.UIAxes
        UIAxes_2            matlab.ui.control.UIAxes
        UIAxes_1            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        origin_img % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            filePath="C:\Users\y-nis\OneDrive\デスクトップ\matlabLesson\logo_csse.png"
            app.origin_img=imread(filePath)
            imshow(app.origin_img,'Parent',app.UIAxes_1)
        end

        % Button pushed function: binarizationButton
        function binarizationButtonPushed(app, event)
            binary_img=rgb2gray(app.origin_img)
            imshow(binary_img,'Parent',app.UIAxes_2)
        end

        % Button pushed function: flipButton
        function flipButtonPushed(app, event)
            flipped_img=flip(app.origin_img)
            imshow(flipped_img,'Parent',app.UIAxes_3)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_1 = uiaxes(app.UIFigure);
            title(app.UIAxes_1, '読込画像')
            xlabel(app.UIAxes_1, 'X')
            ylabel(app.UIAxes_1, 'Y')
            app.UIAxes_1.XTick = [];
            app.UIAxes_1.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '白黒画像')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '反転画像')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.flipButton = uibutton(app.UIFigure, 'push');
            app.flipButton.ButtonPushedFcn = createCallbackFcn(app, @flipButtonPushed, true);
            app.flipButton.Text = 'flip';
            app.binarizationButton = uibutton(app.UIFigure, 'push');
            app.binarizationButton.ButtonPushedFcn = createCallbackFcn(app, @binarizationButtonPushed, true);
            app.binarizationButton.Text = 'binarization';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = assignment1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end